classdef training_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SelectdirButton               matlab.ui.control.Button
        imagesListBoxLabel            matlab.ui.control.Label
        imagesListBox                 matlab.ui.control.ListBox
        TextArea                      matlab.ui.control.TextArea
        RightPanel                    matlab.ui.container.Panel
        TextArea_2                    matlab.ui.control.TextArea
        TrainingdatabrowsingappLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        training_dir % Description
        data % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectdirButton
        function SelectdirButtonPushed(app, event)
            app.training_dir = uigetdir
            app.TextArea.Value = app.training_dir
            files = struct2table(dir(app.training_dir+"\*.png"))
            app.imagesListBox.Items = files.name
        end

        % Value changed function: imagesListBox
        function imagesListBoxValueChanged(app, event)
            file = app.imagesListBox.Value;
            imdata = imread(app.training_dir+"/"+file)
            app.data = imdata
            data = readtable(app.training_dir+"\data.csv")
            app.TextArea_2.Value = file
            imshow(imdata,"Parent",app.UIAxes)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectdirButton = uibutton(app.LeftPanel, 'push');
            app.SelectdirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdirButtonPushed, true);
            app.SelectdirButton.Text = 'Select dir';
            app.imagesListBoxLabel = uilabel(app.LeftPanel);
            app.imagesListBoxLabel.Text = 'images';
            app.imagesListBox = uilistbox(app.LeftPanel);
            app.imagesListBox.Items = {};
            app.imagesListBox.ValueChangedFcn = createCallbackFcn(app, @imagesListBoxValueChanged, true);
            app.imagesListBox.Value = {};
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Enable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.TextArea_2 = uitextarea(app.RightPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Enable = 'off';
            app.TrainingdatabrowsingappLabel = uilabel(app.RightPanel);
            app.TrainingdatabrowsingappLabel.Text = 'Training data browsing app';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = training_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end